classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StopButton                     matlab.ui.control.StateButton
        StartButton                    matlab.ui.control.StateButton
        YellowLightTimeEditField       matlab.ui.control.NumericEditField
        YellowLightTimeEditFieldLabel  matlab.ui.control.Label
        RedLightTimeEditField          matlab.ui.control.NumericEditField
        RedLightTimeEditFieldLabel     matlab.ui.control.Label
        GreenLightTimeEditField        matlab.ui.control.NumericEditField
        GreenLightTimeEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
         Running = false;  % Flag to check if the simulation is running 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GreenLightTimeEditField
        function GreenLightTime(app, event)
            value1 = app.GreenLightTimeEditField.Value;
            
        end

        % Value changed function: YellowLightTimeEditField
        function YellowLightTime(app, event)
            value2 = app.YellowLightTimeEditField.Value;
            
        end

        % Value changed function: RedLightTimeEditField
        function RedLightTime(app, event)
            value3 = app.RedLightTimeEditField.Value;
            
        end

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
              
    % Disable the Start button and enable the Stop button for the duration of the simulation


    app.Running = true;  % Set a flag to indicate that simulation is running


        % Insert your traffic light logic here
        % Be sure to use x, y, and z for the time durations
    x = app.GreenLightTimeEditField.Value;
    y = app.YellowLightTimeEditField.Value;
    z = app.RedLightTimeEditField.Value;
        % Insert pauses and/or drawnow as needed to ensure your UI updates and responds to the Stop button
      visualize_traffic_lights_with_pedestrian(x, y, z);  
        pause(0.1);  % Or a suitable pause time for your simulation
        drawnow limitrate;


        end

        % Value changed function: StopButton
        function StopButtonPushed(app, event)
            % Set the Running flag to false. This will end the while loop in the StartButtonPushed function.
    app.Running = false;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GreenLightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.GreenLightTimeEditFieldLabel.Text = 'Green Light Time';
            app.GreenLightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenLightTimeEditField.ValueChangedFcn = createCallbackFcn(app, @GreenLightTime, true);
            app.GreenLightTimeEditField.Value = 5;
            app.RedLightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RedLightTimeEditFieldLabel.Text = 'Red Light Time';
            app.RedLightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedLightTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RedLightTime, true);
            app.RedLightTimeEditField.Value = 5;
            app.YellowLightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.YellowLightTimeEditFieldLabel.Text = 'Yellow Light Time';
            app.YellowLightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.YellowLightTimeEditField.ValueChangedFcn = createCallbackFcn(app, @YellowLightTime, true);
            app.YellowLightTimeEditField.Value = 2;
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'StopButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end